classdef mainGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainWin                      matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadDataMenu                 matlab.ui.container.Menu
        LoadfromRawMenu              matlab.ui.container.Menu
        LoadfromGUIstateMenu         matlab.ui.container.Menu
        DeleteDataMenu               matlab.ui.container.Menu
        DeleteDatasetsMenu           matlab.ui.container.Menu
        DeleteTrialsMenu             matlab.ui.container.Menu
        DeleteCellsMenu              matlab.ui.container.Menu
        ExportDataMenu               matlab.ui.container.Menu
        ExportFullMenu               matlab.ui.container.Menu
        ExportMinimalMenu            matlab.ui.container.Menu
        SettingsMenu                 matlab.ui.container.Menu
        SetDefaultsMenu              matlab.ui.container.Menu
        GUIMenu                      matlab.ui.container.Menu
        GeneralPlottingMenu          matlab.ui.container.Menu
        RateMapsMenu                 matlab.ui.container.Menu
        PauseUpdatesMenu             matlab.ui.container.Menu
        FreezeMenu                   matlab.ui.container.Menu
        UnFreezeMenu                 matlab.ui.container.Menu
        FiguresMenu                  matlab.ui.container.Menu
        SaveasPDFMenu                matlab.ui.container.Menu
        AllMenu                      matlab.ui.container.Menu
        GUIStateMenu                 matlab.ui.container.Menu
        CustomSelectionMenu          matlab.ui.container.Menu
        NeuropixelsMenu              matlab.ui.container.Menu
        CellsMenu                    matlab.ui.container.Menu
        plotWaveDistMenu             matlab.ui.container.Menu
        DACQMenu                     matlab.ui.container.Menu
        FilterMenu                   matlab.ui.container.Menu
        TrialsinDatasetListBoxLabel  matlab.ui.control.Label
        TrialsinDatasetListBox       matlab.ui.control.ListBox
        CellsinDatasetListBoxLabel   matlab.ui.control.Label
        CellsinDatasetListBox        matlab.ui.control.ListBox
        ExportDataButton             matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        OverviewTab                  matlab.ui.container.Tab
        wfs                          matlab.ui.control.UIAxes
        ac_shortLag                  matlab.ui.control.UIAxes
        spikepos                     matlab.ui.control.UIAxes
        rmap                         matlab.ui.control.UIAxes
        varmap                       matlab.ui.control.UIAxes
        dmap                         matlab.ui.control.UIAxes
        nSpikesTxt                   matlab.ui.control.EditField
        nSpikes                      matlab.ui.control.NumericEditField
        amplitudeTxt                 matlab.ui.control.EditField
        isodTxt                      matlab.ui.control.EditField
        lratioTxt                    matlab.ui.control.EditField
        amplitude                    matlab.ui.control.NumericEditField
        lratio                       matlab.ui.control.NumericEditField
        isod                         matlab.ui.control.NumericEditField
        MakeRMapsButton              matlab.ui.control.Button
        varmapButton                 matlab.ui.control.Button
        LoadWaveForms                matlab.ui.control.Button
        MakeDMapsButton              matlab.ui.control.Button
        ac_longLag                   matlab.ui.control.UIAxes
        PanelAllTrials               matlab.ui.container.Panel
        SelectPlotstyleLabel         matlab.ui.control.Label
        varMapList                   matlab.ui.control.ListBox
        cluLabelTxt                  matlab.ui.control.EditField
        cluLabel                     matlab.ui.control.EditField
        CompareCellsTab              matlab.ui.container.Tab
        dummy                        matlab.ui.control.UIAxes
        dummy_2                      matlab.ui.control.UIAxes
        dummy_3                      matlab.ui.control.UIAxes
        dummy_4                      matlab.ui.control.UIAxes
        dummy_5                      matlab.ui.control.UIAxes
        dummy_6                      matlab.ui.control.UIAxes
        dummy_7                      matlab.ui.control.UIAxes
        dummy_8                      matlab.ui.control.UIAxes
        EditField_A1                 matlab.ui.control.EditField
        EditField_B1                 matlab.ui.control.EditField
        CC_selCellsB                 matlab.ui.control.ListBox
        CC_selCellsA                 matlab.ui.control.ListBox
        CompareDropDownLabel         matlab.ui.control.Label
        CompareDropDown              matlab.ui.control.DropDown
        LineariseMeTab               matlab.ui.container.Tab
        PlotSelectionButton          matlab.ui.control.Button
        LoadDataButton               matlab.ui.control.Button
        DatasetLabel                 matlab.ui.control.Label
        DatasetEditField             matlab.ui.control.EditField
        TrialEditFieldLabel          matlab.ui.control.Label
        TrialEditField               matlab.ui.control.EditField
        CellEditFieldLabel           matlab.ui.control.Label
        CellEditField                matlab.ui.control.EditField
        SelectDatasetsListBoxLabel   matlab.ui.control.Label
        SelectDatasetsListBox        matlab.ui.control.ListBox
        SelectAllCells               matlab.ui.control.Button
        SelectAllTrials              matlab.ui.control.Button
        ExportFiguresButton          matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        Button_3                     matlab.ui.control.Button
        SelectAllDatasets            matlab.ui.control.Button
    end

       
    % helpers
    methods (Access = private)
        
        function setFieldsGUI(app,event)
        
            
            currDsSelInd = app.mainWin.UserData.currSelInd(1);
            currTrSelInd = app.mainWin.UserData.currSelInd(2);
            currCeSelInd = app.mainWin.UserData.currSelInd(3);
            
            app.DatasetEditField.Value      = app.mainWin.UserData.dataObj{currDsSelInd}.dataSetName;
            app.SelectDatasetsListBox.Value = app.mainWin.UserData.dataObj{currDsSelInd}.dataSetName;
            % trial & cell selection
            app.TrialEditField.Value        = app.mainWin.UserData.dataObj{currDsSelInd}.trialNames{currTrSelInd};
            app.CellEditField.Value         = app.mainWin.UserData.cellIDStr{currDsSelInd}{currCeSelInd};
            % some special case if dataset selection changed or GUI is
            % started
            if strcmpi(event.Source.Tag,'dsselbox') || isempty(app.TrialsinDatasetListBox.Items) || isempty(app.CellsinDatasetListBox.Items)
                app.TrialsinDatasetListBox.Items = app.mainWin.UserData.dataObj{currDsSelInd}.trialNames;
                app.TrialsinDatasetListBox.Value = app.mainWin.UserData.dataObj{currDsSelInd}.trialNames{1};
                app.CellsinDatasetListBox.Items  = app.mainWin.UserData.cellIDStr{currDsSelInd};
                app.CellsinDatasetListBox.Value  = app.mainWin.UserData.cellIDStr{currDsSelInd}{1};
                app.CC_selCellsA.Items  = app.mainWin.UserData.cellIDStr{currDsSelInd};
                app.CC_selCellsA.Value  = app.mainWin.UserData.cellIDStr{currDsSelInd}{1};
                app.CC_selCellsB.Items  = app.mainWin.UserData.cellIDStr{currDsSelInd};
                app.CC_selCellsB.Value  = app.mainWin.UserData.cellIDStr{currDsSelInd}{min([2,length(app.mainWin.UserData.cellIDStr{currDsSelInd})])};
            end
            
            switch lower(app.TabGroup.SelectedTab.Title)
                case 'overview'

                    app.nSpikes.Value   = length(app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_Times{currTrSelInd}{currCeSelInd});
                    if ~isempty(app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_waveforms{1})
                        tempWFs             = squeeze(nanmean(app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_waveforms{currTrSelInd}{currCeSelInd,1},1));
                        if ~all(isnan(tempWFs))
                            app.amplitude.Value = round(max(abs(min(tempWFs,[],1) - max(tempWFs,[],1))),1);
                        else
                             app.amplitude.Value = 0;
                        end
                    end
                    if strcmp(app.mainWin.UserData.dataType,'scanpix.npix')
                        app.cluLabel.Value = app.mainWin.UserData.dataObj{currDsSelInd}.cell_Label{currCeSelInd};
                    end
                    %             app.lratio.Value    = length(app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_times{currTrSelInd}{currCeSelInd});
                    %             app.isoD.Value      = length(app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_times{currTrSelInd}{currCeSelInd});
                    
                    % update figures
                    if app.wfs.UserData.plot;         plotWave(app);          else; cla(app.wfs);         app.wfs.Visible='On';         end
                    if app.ac_shortLag.UserData.plot; plotAC(app,'shortLag'); else; cla(app.ac_shortLag); app.ac_shortLag.Visible='On'; end
                    if app.ac_longLag.UserData.plot;  plotAC(app,'longLag');  else; cla(app.ac_longLag);  app.ac_longLag.Visible='On';  end
                    if app.rmap.UserData.plot;        plotRMap(app);          else; cla(app.rmap);        app.rmap.Visible='On';        end
                    if app.dmap.UserData.plot;        plotDMap(app);          else; cla(app.dmap);        app.dmap.Visible='On';        end
                    if app.spikepos.UserData.plot;    plotSpkPos(app);        else; cla(app.spikepos);    app.spikepos.Visible='On';    end
                    if ~strcmpi(event.Source.Tag,'trialselbox')
                        plotAllTrials(app);
                    end
                    
                    if app.varmap.UserData.plot
                        switch lower(app.varMapList.Value)
                            case 'spatial ac'
                                plot_sACs(app);
                            case 'speed mod.'
                        end
                    else
                        cla(app.varmap); app.varmap.Visible='On';
                        app.varmap.Title.String = 'Var. Data Plot';
                    end
                case 'compare cells'
                    
                case 'linearise me'
                    
            end
            
            figure(app.mainWin);
        end
        
        function plotAC(app,lagType)
            
            hAx = app.(['ac_' lagType]);
            binSz  = app.mainWin.UserData.figDefaults.(['ac_bSz_' lagType]);
            maxLag = app.mainWin.UserData.figDefaults.(['ac_lag_' lagType]);
            
            ds_ind = app.mainWin.UserData.currSelInd(1);
            tr_ind = app.mainWin.UserData.currSelInd(2);
            ce_ind = app.mainWin.UserData.currSelInd(3);
            ac = scanpix.spk_crosscorr(app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_Times{tr_ind}{ce_ind},'AC',binSz,maxLag,app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind).duration,'plot',hAx);
            
            if all(isnan(ac))
                maxY = 1;
            else
                maxY = 1.1*max(ac);
            end
            
            axis(hAx,'square');
            set(hAx,'xlim',[-maxLag maxLag],'xtick',[-maxLag 0 maxLag],'xticklabel',[-maxLag*1000 0 maxLag*1000],'ylim',[0 maxY]);
            title(hAx,['AC ' num2str(maxLag*1000) 'ms']);
        end
        
        function setDefaults(app,type,field,value)
            
            if nargin == 2; [field, value] = scanpix.GUI.grabDefaults_mainGUI(type); end
        
            for i = 1:length(field)
                switch lower(type)
                    case 'gui'
                        app.mainWin.UserData.GUIDefaults.(field{i}) = value{i};
                    case 'fig'
                        app.mainWin.UserData.figDefaults.(field{i}) = value{i};
                    case 'maps'
                        app.mainWin.UserData.figDefaults.mapParams.(field{i}) = value{i};
                end 
            end
        end
        
        function plotRMap(app)
            ds_ind = app.mainWin.UserData.currSelInd(1);
            tr_ind = app.mainWin.UserData.currSelInd(2);
            ce_ind = app.mainWin.UserData.currSelInd(3);
            
            if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{tr_ind}); cla(app.rmap); app.rmap.Visible='On'; return; end
            % plot
            scanpix.maps.plotRateMap(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{tr_ind}{ce_ind},app.rmap,'colmap',app.mainWin.UserData.figDefaults.rMapColMap,'nsteps',app.mainWin.UserData.figDefaults.rMapColNSteps)
            axis(app.rmap,'square');
            mapSz = size(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{tr_ind}{ce_ind});
            set(app.rmap,'ydir','normal','xlim',[0 mapSz(2)],'ylim',[0 mapSz(1)]);
            set(get(app.rmap,'xlabel'),'String',['peakFR=' num2str(max(max(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{tr_ind}{ce_ind})),'%.1f')],'Visible','On');
        end
        
        function plotDMap(app)
            ds_ind = app.mainWin.UserData.currSelInd(1);
            tr_ind = app.mainWin.UserData.currSelInd(2);
            ce_ind = app.mainWin.UserData.currSelInd(3);
            
            if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{tr_ind}); cla(app.dmap); app.dmap.Visible='On';  return; end
            
            map = app.mainWin.UserData.dataObj{ds_ind}.maps.dir{tr_ind}{ce_ind};
            
            scanpix.maps.plotDirMap(map,app.dmap);
            set(get(app.dmap,'xlabel'),'String',['peakFR=' num2str(max(max(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{tr_ind}{ce_ind})),'%.1f')],'Visible','On');
        end
        
        function plotSpkPos(app)
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            tr_ind   = app.mainWin.UserData.currSelInd(2);
            ce_ind   = app.mainWin.UserData.currSelInd(3);
            pos      = app.mainWin.UserData.dataObj{ds_ind}.posData.XY{tr_ind};
            spkTimes = app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_Times{tr_ind}{ce_ind};
            
            scanpix.maps.plotSpikeByPos(pos,spkTimes,app.spikepos,'srate',app.mainWin.UserData.dataObj{ds_ind}.params('posFs'));
            axis(app.spikepos,'square');
            set(app.spikepos,'xlim',[min(pos(:,1)) max(pos(:,1))],'ylim',[min(pos(:,2)) max(pos(:,2))]);
            set(get(app.spikepos,'xlabel'),'String',' ','Visible','On');
        end
        
        function plotWave(app)
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            tr_ind   = app.mainWin.UserData.currSelInd(2);
            ce_ind   = app.mainWin.UserData.currSelInd(3);
            
            if isempty(app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_waveforms{tr_ind}); cla(app.wfs); app.wfs.Visible='On'; return; end
           
            scanpix.plot.plotWaveform(app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_waveforms{tr_ind}{ce_ind,1}, app.wfs,'maxWaves',app.mainWin.UserData.figDefaults.nWaveForms);

            if strcmp(app.mainWin.UserData.dataType,'scanpix.dacq')
                tetN    = app.mainWin.UserData.dataObj{ds_ind}.cell_ID(ce_ind,2);
                meanWFs = squeeze(nanmean(app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_waveforms{tr_ind}{ce_ind,1},1));
                [~, maxChanInd] = max(abs(min(meanWFs,[],1) - max(meanWFs,[],1)));
                if ~isempty(maxChanInd)
                    scaleChan = app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind).ADC_fullscale_mv / app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind).gains(tetN,maxChanInd) * 1e3;
                    set(app.wfs,'ylim',[-scaleChan scaleChan],'yTick',[-scaleChan scaleChan],'YtickLabel',round([-scaleChan scaleChan]));
                end
            else
                %% NPIX GOES HERE %%%
            end
        end
        
        function plotAllTrials(app)
        
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            ce_ind   = app.mainWin.UserData.currSelInd(3);
            
            if ~app.PanelAllTrials.UserData.plot || (isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{1}) && isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{1}) )
                delete(app.PanelAllTrials.Children);
                return;
            end
            
            currPosSt = [5,125,120,120]; currPos = currPosSt;
            if ~isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{1})
                mapSz = size(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{1}{1});
            end
            
            nMapStyle = ~isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{1}) + ~isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{1});
            axCheck = length(app.PanelAllTrials.Children) < nMapStyle * length(app.mainWin.UserData.dataObj{ds_ind}.trialNames);
            for i = 1:length(app.mainWin.UserData.dataObj{ds_ind}.trialNames)

                if ~isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{1})
                    % check if axis exist or not
                    if axCheck && ( isempty(app.PanelAllTrials.Children) || ~any([app.PanelAllTrials.Children(:).UserData] == 2*i-1) )
                        hAx = uiaxes(app.PanelAllTrials,'position',currPos);
                        % we need to tag axes here to keep track of order
                        % later
                        app.PanelAllTrials.Children(1).UserData = 2*i-1;  % Axes in Children are appended to top of the list!
                    else
                        ind = [app.PanelAllTrials.Children(:).UserData] == 2*i-1;
                        hAx = app.PanelAllTrials.Children(ind); 
                    end
                    scanpix.maps.plotRateMap(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{i}{ce_ind},hAx,'colmap',app.mainWin.UserData.figDefaults.rMapColMap,'nsteps',app.mainWin.UserData.figDefaults.rMapColNSteps)
                    % format axis
                    axis(hAx,'square');
                    set(hAx,'ydir','normal','xlim',[0 mapSz(2)],'ylim',[0 mapSz(1)]);
                    set(get(hAx,'xlabel'),'String',['peakFR=' num2str(nanmax(nanmax(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{i}{ce_ind})),'%.1f')],'Visible','On','Verticalalignment','bottom');
                    title(hAx,app.mainWin.UserData.dataObj{ds_ind}.trialNames{i},'Interpreter','none');
                end
                
                if ~isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{1})
                    if axCheck && ( isempty(app.PanelAllTrials.Children) || ~any([app.PanelAllTrials.Children(:).UserData] == 2*i) )
                        hAx = uiaxes(app.PanelAllTrials,'position',currPos - [0 120 0 0]);
                        app.PanelAllTrials.Children(1).UserData = 2*i;
                    else
                        ind =[app.PanelAllTrials.Children(:).UserData] == 2*i;
                        hAx = app.PanelAllTrials.Children(ind); 
                    end
                    scanpix.maps.plotDirMap(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{i}{ce_ind}, hAx);
                    set(get(hAx,'xlabel'),'String',['peakFR=' num2str(nanmax(nanmax(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{i}{ce_ind})),'%.1f')],'Visible','On','Verticalalignment','bottom');
                end
                currPos(1) = sum(currPos([1 3]));

            end
  
        end
        
        function str = freezePlotsSel(app,mode,varargin)
            
            axisNames  = {'wfs',      'ac_shortLag', 'ac_longLag', 'spikepos',    'rmap',    'varmap',        'dmap',   'PanelAllTrials'};
            defaultStr = {'Waveforms','AC short Lag','AC long lag','Spike by Pos','Rate Map','Var. Data Plot','Dir Map','All Trials Panel'};
            switch mode
                case 'get'
                    ind = false(1,length(defaultStr));
                    for i = 1:length(defaultStr)
                        ind(i) = app.(axisNames{i}).UserData.plot;
                    end
                    if varargin{1}
                        str = defaultStr(ind);
                    else
                        str = defaultStr(~ind);
                    end
                case 'set'
                    for i = 1:length(varargin{1})
                        ind = strcmp(varargin{1}{i},defaultStr);
                        app.(axisNames{ind}).UserData.plot = ~app.(axisNames{ind}).UserData.plot;
                    end
                    str = {};
                case 'init'
                    for i = 1:length(axisNames)
                        app.(axisNames{i}).UserData.plot = true;
                    end 
            end
            
        end
        
        function plot_sACs(app)
            ds_ind = app.mainWin.UserData.currSelInd(1);
            tr_ind = app.mainWin.UserData.currSelInd(2);
            ce_ind = app.mainWin.UserData.currSelInd(3);
            
            if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.sACs{tr_ind}); cla(app.varmap); app.varmap.Visible='On'; title(app.varmap,'Var. Data Plot'); return; end
            % plot
            imagesc(app.varmap,app.mainWin.UserData.dataObj{ds_ind}.maps.sACs{tr_ind}{ce_ind}); colormap(app.varmap,app.mainWin.UserData.figDefaults.sACColMap);
            axis(app.varmap,'square');
            title(app.varmap,'Spatial AC');
            mapSz = size(app.mainWin.UserData.dataObj{ds_ind}.maps.sACs{tr_ind}{ce_ind});
            set(app.varmap,'ydir','normal','xlim',[0 mapSz(2)],'ylim',[0 mapSz(1)]);
            set(get(app.varmap,'xlabel'),'String',' ','Visible','On');
        end
        
        function makeSACs(app)
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            if isempty( app.mainWin.UserData.dataObj{ds_ind}.maps.rate{1})
                warning([app.mainWin.Name '-GUI:MakeSACs:You need to make rate maps first before you can generate spatial ACs.']);
                return;
            end
            for i = 1:length(app.mainWin.UserData.dataObj{ds_ind}.trialNames)
                app.mainWin.UserData.dataObj{ds_ind}.maps.sACs{i} = cellfun(@(x) scanpix.maps.map_crosscorr(x,x), app.mainWin.UserData.dataObj{ds_ind}.maps.rate{i},'uni',0);
            end
        end
        
        function exportData(app,minFlag)
            [~, ind] = ismember(app.SelectDatasetsListBox.Value, app.SelectDatasetsListBox.Items);
            objData = app.mainWin.UserData.dataObj(ind);
            
            if minFlag
                % remove all map data
                f = fieldnames(objData{1}.maps);
                for i = 1:length(f)
                    for j = 1:length(objData)
                        objData{j}.maps.(f{i})(:) = {[]};
                    end
                end
                f = fieldnames(objData{1}.linMaps);
                for i = 1:length(f)
                    for j = 1:length(objData)
                        objData{j}.maps.(f{i})(:) = {[]};
                    end
                end
            end
%             dataType    = app.mainWin.UserData.dataType;
            GUIDefaults = app.mainWin.UserData.GUIDefaults;
            cellIDStr = app.mainWin.UserData.cellIDStr(ind);
            fNameOut    = fullfile(app.mainWin.UserData.GUIDefaults.outputDir,['GUIState_' app.mainWin.UserData.dataType(9:end) '_' app.mainWin.UserData.GUIDefaults.filename_suffix '.mat']);
            fNameOut    = scanpix.helpers.checkSaveFile(fNameOut);
            save(fNameOut,'objData','GUIDefaults','cellIDStr');
        end
        
        
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dataType)
            
            setDefaults(app,'gui');
            setDefaults(app,'fig');
            setDefaults(app,'maps');
            % set a few things programmatically
            app.MakeRMapsButton.Tag        = 'rate';
            app.MakeDMapsButton.Tag        = 'dir';
            app.TrialsinDatasetListBox.Tag = 'TrialSelBox';
            app.SelectDatasetsListBox.Tag  = 'DSSelBox';
            freezePlotsSel(app,'init');
%             baseSz = [100 100 1220 760];
%             screenSize = get(0,'ScreenSize');
%             drawnow;
%             app.mainWin.Position = [0.1*screenSize(1:2) 0.85*screenSize(3) 0.8*screenSize(4)];
            app.mainWin.UserData.dataObj = {};
            
            if nargin == 1 || isempty(dataType)
                str = {'dacq','npix'};
                [select, loadCheck] = listdlg('PromptString','Select what type of Data You Want to Browse:','ListString',str,'ListSize',[250 50],'SelectionMode','Single');
                if ~loadCheck
                    delete(app);
                    return;
                end
                str = str{select};
            else
                str = dataType;
            end
            app.mainWin.Name =  ['scaNpix.' str];
            app.mainWin.UserData.dataType = ['scanpix.', str];
            
            baseVarInfo = evalin('base', 'whos');
            ind = strcmp({baseVarInfo(:).class}, app.mainWin.UserData.dataType);
            varNames = {baseVarInfo(ind).name};
            
            if isempty(varNames)                
                figure(app.mainWin);
                return;
            end
            % select what to import
            [select, loadCheck] = listdlg('PromptString','Select Which Obj from Workspace You Want to Import:','ListString',varNames,'ListSize',[250 50]);
            if ~loadCheck
                figure(app.mainWin);
                return;
            end
            varNames = varNames(select);
            % assign selected data to obj
            for i = 1:length(varNames)
                tempObj =  evalin('base', varNames{i});
                
                app.mainWin.UserData.dataObj{i}    = tempObj;
                app.SelectDatasetsListBox.Items{i} = tempObj.dataSetName;
                
                switch app.mainWin.UserData.dataType
                    case 'scanpix.dacq'
                        app.mainWin.UserData.cellIDStr{i} = strrep(string(strcat('c',cellstr(num2str(tempObj.cell_ID(:,1))),'t',cellstr(num2str(tempObj.cell_ID(:,2))))),' ','');
                    case 'scanpix.npix'
                        app.mainWin.UserData.cellIDStr{i} = strrep(string(strcat('clu_',num2str(tempObj.cell_ID(:,1)))),' ','');
                        app.LoadWaveForms.Visible = 'On';
                        app.LoadWaveForms.Enable  = 'On';
                end
                
            end
            app.mainWin.UserData.currSelInd = [1 1 1];           
            setFieldsGUI(app,[]);
        end

        % Callback function: LoadDataButton, LoadfromRawMenu
        function LoadDataButtonPushed(app, event)
            if isempty(app.mainWin.UserData.dataObj)
                assignInd = 1;
            else
                assignInd = length(app.mainWin.UserData.dataObj) + 1;
            end
            
            switch app.mainWin.UserData.dataType
                case 'scanpix.dacq'
                    tempObj = scanpix.dacq('ui');
                    if isempty(tempObj.trialNames); return; end
                    tempObj.load({'pos','spikes'});
                    if ~tempObj.loadFlag || isempty(tempObj.spikeData.spk_Times{1}); figure(app.mainWin); return; end
                    app.mainWin.UserData.cellIDStr{assignInd} = strcat('c',strip(cellstr(num2str(tempObj.cell_ID(:,1)))),'t',strip(cellstr(num2str(tempObj.cell_ID(:,2))))); 
                case 'scanpix.npix'
                    tempObj = scanpix.npix('ui');
                    if isempty(tempObj.trialNames); return; end
                    tempObj.load({'pos','spikes'});
                    if ~tempObj.loadFlag || isempty(tempObj.spikeData.spk_Times{1}); figure(app.mainWin); return; end
                    app.mainWin.UserData.cellIDStr{assignInd} = strrep(string(strcat('clu_',num2str(tempObj.cell_ID(:,1)))),' ','');
                    app.LoadWaveForms.Visible = 'On';
                    app.LoadWaveForms.Enable  = 'On';
            end
            
            app.mainWin.UserData.dataObj{assignInd}    = tempObj;

            % make sure name is unique
            ds_name = tempObj.dataSetName;
            c = 2;
            while any(strcmp(app.SelectDatasetsListBox.Items,ds_name))
                ds_name = [ds_name '_' num2str(c)]; %#okagrow
                c = c+1;
            end
            app.SelectDatasetsListBox.Items{assignInd}          = ds_name;
            app.mainWin.UserData.dataObj{assignInd}.dataSetName = ds_name;
            app.mainWin.UserData.currSelInd                     = [length(app.mainWin.UserData.dataObj) 1 1];
            
            setFieldsGUI(app, event);
        end

        % Menu selected function: LoadfromGUIstateMenu
        function LoadfromGUIstateMenuSelected(app, event)
            [fname, path] = uigetfile(fullfile(app.mainWin.UserData.GUIDefaults.outputDir,['GUIState_' app.mainWin.UserData.dataType(9:end) '*.mat']),'Select GUI State Data');
            if ~ischar (fname); return; end
            tmp = load(fullfile(path,fname));
            
            if isempty(app.mainWin.UserData.dataObj)
                assignInd = 1;
%                 app.mainWin.UserData.dataObj     = tmp.objData;
                app.mainWin.UserData.GUIDefaults = tmp.GUIDefaults;
            else
                assignInd = length(app.mainWin.UserData.dataObj) + 1;
%                 app.mainWin.UserData.dataObj{assignInd:assignInd+length(tmp.objData)-1} = tmp.objData;
            end
            app.mainWin.UserData.dataObj{assignInd:assignInd+length(tmp.objData)-1}    = tmp.objData{:};
            app.mainWin.UserData.cellIDStr{assignInd:assignInd+length(tmp.objData)-1}  = tmp.cellIDStr{:};
            app.SelectDatasetsListBox.Items{assignInd:assignInd+length(tmp.objData)-1} = tmp.objData{:}.dataSetName;
            app.mainWin.UserData.currSelInd = [assignInd 1 1];
%             app.mainWin.UserData.dataObj{assignInd}.dataSetName = ds_name;
            setFieldsGUI(app, event);
        end

        % Callback function
        function DatasetEditFieldValueChanged(app, event)
%             value = app.DatasetEditField.Value;
            
        end

        % Value changed function: SelectDatasetsListBox
        function SelectDatasetsListBoxValueChanged(app, event)
            [~, ind] = ismember(app.SelectDatasetsListBox.Value, app.SelectDatasetsListBox.Items);
            app.mainWin.UserData.currSelInd = [ind(1) 1 1];
            setFieldsGUI(app, event);    
        end

        % Button pushed function: SelectAllDatasets
        function SelectAllDatasetsButtonPushed(app, event)
            app.SelectDatasetsListBox.Value = app.SelectDatasetsListBox.Items;
        end

        % Value changed function: TrialsinDatasetListBox
        function TrialsinDatasetListBoxValueChanged(app, event)
            [~, ind] = ismember(app.TrialsinDatasetListBox.Value, app.TrialsinDatasetListBox.Items);
            app.mainWin.UserData.currSelInd(2) = ind(1);
            setFieldsGUI(app, event);   
        end

        % Button pushed function: SelectAllTrials
        function SelectAllTrialsPushed(app, event)
            app.TrialsinDatasetListBox.Value = app.TrialsinDatasetListBox.Items;
        end

        % Value changed function: CellsinDatasetListBox
        function CellsinDatasetListBoxValueChanged(app, event)
            [~, ind] = ismember(app.CellsinDatasetListBox.Value, app.CellsinDatasetListBox.Items);
            app.mainWin.UserData.currSelInd(3) = ind(1);
            setFieldsGUI(app, event);   
        end

        % Button pushed function: SelectAllCells
        function SelectAllCellsPushed(app, event)
            app.CellsinDatasetListBox.Value = app.CellsinDatasetListBox.Items;
        end

        % Button pushed function: MakeDMapsButton, MakeRMapsButton
        function MakeRMapsButtonPushed(app, event)
            ds_ind = app.mainWin.UserData.currSelInd(1);
            if strcmp(event.Source.Tag,'rate')
                mapType = 'rate';
            elseif strcmp(event.Source.Tag,'dir')
                mapType = 'dir';
            end
            scanpix.maps.addMaps(app.mainWin.UserData.dataObj{ds_ind},mapType,[],app.mainWin.UserData.figDefaults.mapParams);
            setFieldsGUI(app,event);
        end

        % Value changed function: varMapList
        function varMapListValueChanged(app, event)
            setFieldsGUI(app,event); 
        end

        % Button pushed function: varmapButton
        function varmapButtonPushed(app, event)
            switch lower(app.varMapList.Value)
                case 'spatial ac'
                    makeSACs(app);
                case 'speed mod.'
                    
            end
            
            setFieldsGUI(app,event);   
        end

        % Button pushed function: LoadWaveForms
        function LoadWaveFormsPushed(app, event)
            
            ds_ind = app.mainWin.UserData.currSelInd(1);
            app.mainWin.UserData.dataObj{ds_ind}.loadWaves;
            
            setFieldsGUI(app,event);       
        end

        % Button pushed function: PlotSelectionButton
        function PlotSelectionButtonPushed(app, event)
            
            mapType = {'rate','spike','pos','dir'};
            [select, loadCheck] = listdlg('PromptString','Select what type of Maps You Fancy:','ListString',mapType,'ListSize',[150 75]);
            if ~loadCheck
                return;
            end
            mapType = mapType(select);
            
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            [~, tr_ind] = ismember(app.TrialsinDatasetListBox.Value, app.TrialsinDatasetListBox.Items);
            [~, ce_ind] = ismember(app.CellsinDatasetListBox.Value, app.CellsinDatasetListBox.Items);
            
            for i = 1:length(mapType)
                if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.(mapType{i})); continue; end
                maps = cellfun(@(x) x(ce_ind),app.mainWin.UserData.dataObj{ds_ind}.maps.(mapType{i})(tr_ind),'uni',0);
                scanpix.maps.plotMapsGroup(maps,mapType{i},app.mainWin.UserData.cellIDStr{ds_ind}(ce_ind),'figname',[app.mainWin.UserData.dataObj{ds_ind}.dataSetName '_' mapType{i} 'MapGroup']);
            end
        end

        % Menu selected function: FreezeMenu
        function FreezeMenuSelected(app, event)
            str = freezePlotsSel(app,'get',true);
            if isempty(str); warning([app.mainWin.Name '-GUI:FreezeMenu:Updates for all plots are already frozen mate.']); return; end
            
            [select, loadCheck] = listdlg('PromptString','Select Which Plots You Want to Freeze:','ListString',str,'ListSize',[200 200],'OKString','Freeze');
            if loadCheck
                freezePlotsSel(app,'set',str(select));
            end
            figure(app.mainWin);
            
        end

        % Menu selected function: UnFreezeMenu
        function UnFreezeMenuSelected(app, event)
            str = freezePlotsSel(app,'get',false);
            if isempty(str); warning([app.mainWin.Name '-GUI:UnFreezeMenu:No plots to Unfreeze here pal.']); return; end

            [select, loadCheck] = listdlg('PromptString','Select Which Plots You Want to UnFreeze:','ListString',str,'ListSize',[200 200],'OKString','UnFreeze');
            if loadCheck
                freezePlotsSel(app,'set',str(select));
            end
            figure(app.mainWin);
            
        end

        % Menu selected function: GUIMenu
        function GUIMenuSelected(app, event)
            prompts = fieldnames(app.mainWin.UserData.GUIDefaults);
            defVals  = struct2cell(app.mainWin.UserData.GUIDefaults);
            rtn = scanpix.helpers.makeCustomUIDialogue(prompts,defVals);
            setDefaults(app,'gui',rtn(:,1),rtn(:,2));
        end

        % Menu selected function: GeneralPlottingMenu
        function GeneralPlottingMenuSelected(app, event)
            prompts = fieldnames(app.mainWin.UserData.figDefaults);
            defVals = struct2cell(app.mainWin.UserData.figDefaults);
            ind     = strcmp(prompts,'mapParams');
            prompts = prompts(~ind);
            defVals = defVals(~ind);
%             defVals = cell(length(prompts),1);
%             for i = 1:length(prompts)
%                 defVals{i,1} = app.mainWin.UserData.figDefaults.(prompts{i});
%             end
            rtn = scanpix.helpers.makeCustomUIDialogue(prompts,defVals);
            setDefaults(app,'fig',rtn(:,1),rtn(:,2));

        end

        % Menu selected function: RateMapsMenu
        function RateMapsMenuSelected(app, event)
            prompts = fieldnames(app.mainWin.UserData.figDefaults.mapParams);
            defVals = struct2cell(app.mainWin.UserData.figDefaults.mapParams);
%             defVals = cell(length(prompts),1);
%             for i = 1:length(prompts)
%                 defVals{i} = app.mainWin.UserData.figDefaults.mapParams.(prompts{i});
%             end
            rtn = scanpix.helpers.makeCustomUIDialogue(prompts,defVals);
            setDefaults(app,'maps',rtn(:,1),rtn(:,2));
        end

        % Menu selected function: DeleteDatasetsMenu
        function DeleteDatasetsMenuSelected(app, event)
            [~, ind] = ismember(app.SelectDatasetsListBox.Value, app.SelectDatasetsListBox.Items);
            selInd = true(1,length(app.SelectDatasetsListBox.Items));
            selInd(ind) = false;
            if any(selInd)
                app.mainWin.UserData.dataObj    = app.mainWin.UserData.dataObj(selInd);
                app.SelectDatasetsListBox.Items = app.SelectDatasetsListBox.Items(selInd);
                
                app.mainWin.UserData.currSelInd = [1 1 1];
                setFieldsGUI(app,event);
            else
                [app.mainWin.UserData.dataObj,app.SelectDatasetsListBox.Items,app.TrialsinDatasetListBox.Items,app.CellsinDatasetListBox.Items] = deal({});
                [app.DatasetEditField.Value,app.TrialEditField.Value,app.CellEditField.Value] = deal('');                      
            end
        end

        % Menu selected function: DeleteTrialsMenu
        function DeleteTrialsMenuSelected(app, event)
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            [~, ind] = ismember(app.TrialsinDatasetListBox.Value, app.TrialsinDatasetListBox.Items);
            delInd = false(1,length(app.TrialsinDatasetListBox.Items));
            delInd(ind) = true;
            
            if ~all(delInd)
                scanpix.helpers.deleteData(app.mainWin.UserData.dataObj{ds_ind}, app.mainWin.UserData.dataObj{ds_ind}.trialNames(delInd)); 
                app.mainWin.UserData.currSelInd(2:3) = [1 1];
                setFieldsGUI(app,event);
            else
                hgfeval({@DeleteDatasetsMenuSelected,app,event}); % delete dataset in case all trials were deleted 
            end
            
        end

        % Menu selected function: DeleteCellsMenu
        function DeleteCellsMenuSelected(app, event)
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            [~, ind] = ismember(app.CellsinDatasetListBox.Value, app.CellsinDatasetListBox.Items);
            selInd = true(1,length(app.CellsinDatasetListBox.Items));
            selInd(ind) = false;
            
            if any(selInd)
                f = fieldnames(app.mainWin.UserData.dataObj{ds_ind}.spikeData);
                for i = 1:length(f)
                    app.mainWin.UserData.dataObj{ds_ind}.spikeData.(f{i}) = cellfun(@(x) x(selInd),app.mainWin.UserData.dataObj{ds_ind}.spikeData.(f{i}),'uni',0);
                end
                f = fieldnames(app.mainWin.UserData.dataObj{ds_ind}.maps);
                for i = 1:length(f)
                    if ~isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.(f{i}){1})
                        app.mainWin.UserData.dataObj{ds_ind}.maps.(f{i}) = cellfun(@(x) x(selInd),app.mainWin.UserData.dataObj{ds_ind}.maps.(f{i}),'uni',0);
                    end
                end
                app.mainWin.UserData.dataObj{ds_ind}.cell_ID = app.mainWin.UserData.dataObj{ds_ind}.cell_ID(selInd,:);
                if strcmp(app.mainWin.UserData.dataType,'scanpix.npix')
                    app.mainWin.UserData.dataObj{ds_ind}.cell_Label = app.mainWin.UserData.dataObj{ds_ind}.cell_Label(selInd,:);
                end
                app.mainWin.UserData.cellIDStr{ds_ind} = app.mainWin.UserData.cellIDStr{ds_ind}(selInd,:);
                
                app.mainWin.UserData.currSelInd(3) = 1;
                setFieldsGUI(app,event);
            else
                hgfeval({@DeleteDatasetsMenuSelected,app,event}); % delete dataset in case all cells were deleted 
            end
        end

        % Callback function: ExportDataButton, ExportFullMenu
        function ExportFullMenuSelected(app, event)
            exportData(app,false);
        end

        % Menu selected function: ExportMinimalMenu
        function ExportMinimalMenuSelected(app, event)
            exportData(app,true);
        end

        % Value changed function: DatasetEditField
        function DatasetEditFieldValueChanged2(app, event)
            [~, ind]                                      = ismember(app.SelectDatasetsListBox.Items, event.PreviousValue);
            app.SelectDatasetsListBox.Items{ind}          = app.DatasetEditField.Value;
            app.mainWin.UserData.dataObj{ind}.dataSetName = app.DatasetEditField.Value;
            
        end

        % Value changed function: CC_selCellsA
        function CC_selCellsAValueChanged(app, event)
            app.EditField_A1.value = app.CC_selCellsA.Value;    
        end

        % Value changed function: CC_selCellsB
        function CC_selCellsBValueChanged(app, event)
            app.EditField_B1.value = app.CC_selCellsB.Value; 
        end

        % Menu selected function: plotWaveDistMenu
        function plotWaveDistMenuSelected(app, event)
            
            currDsSelInd = app.mainWin.UserData.currSelInd(1);
            currTrSelInd = app.mainWin.UserData.currSelInd(2);
            currCeSelInd = app.mainWin.UserData.currSelInd(3);
            
            if isempty(app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_waveforms{1})
                warning('You need to load waveforms first before plotting them.... kinda obvious, isn''t it?');
                return;
            else
                figSz  = [250 400];
                plotSz = [50 150];
                screenSz = get(0,'ScreenSize');
                hFig = figure('Name',['WaveDist_clu_' num2str(app.mainWin.UserData.dataObj{currDsSelInd}.cell_ID(currCeSelInd,1))],'Units','pixels','Position',[0.5*screenSz(3)-figSz(1)/2 0.5*screenSz(4)-figSz(2)/2 figSz]);
                
                idx    = ismember(app.mainWin.UserData.dataObj{currDsSelInd}.chanMap.chanMap,app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_waveforms{currTrSelInd}{currCeSelInd,2});
                coords = [app.mainWin.UserData.dataObj{currDsSelInd}.chanMap.xcoords(idx) app.mainWin.UserData.dataObj{currDsSelInd}.chanMap.ycoords(idx)];
                shifts = [diff(coords,[],1);0 0]; %%%%%%%%%%NEEDS WORK %%%%%
                
                currWave = app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_waveforms{currTrSelInd}{currCeSelInd,1};
                offSet = [ plotSz(1) figSz(2)-100];
                for i = 1:size(currWave,3)
                    tempAx = axes(hFig,'units','pixel','Position',[coords(i,1)+offSet(1) offSet(2) plotSz]);
                    scanpix.plot.plotWaveform(currWave(:,:,i),tempAx,'plotIndWFs',false);
                    text(tempAx)
                    axis(tempAx,'off');
                    if shifts(i,1) > 0
                        offSet(1) = offSet(1) + plotSz(1);
                    else
                        offSet(1) = offSet(1) - plotSz(1);
                    end
                    offSet(2) = offSet(2) - shifts(i,2)*3;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainWin = uifigure('Visible', 'off');
            app.mainWin.Name = 'UI Figure';
            app.FileMenu = uimenu(app.mainWin);
            app.FileMenu.Text = 'File';
            app.LoadDataMenu = uimenu(app.FileMenu);
            app.LoadDataMenu.Text = 'Load Data';
            app.LoadfromRawMenu = uimenu(app.LoadDataMenu);
            app.LoadfromRawMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadfromRawMenu.Text = 'from Raw';
            app.LoadfromGUIstateMenu = uimenu(app.LoadDataMenu);
            app.LoadfromGUIstateMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadfromGUIstateMenuSelected, true);
            app.LoadfromGUIstateMenu.Text = 'from GUI state';
            app.DeleteDataMenu = uimenu(app.FileMenu);
            app.DeleteDataMenu.Text = 'Delete Data';
            app.DeleteDatasetsMenu = uimenu(app.DeleteDataMenu);
            app.DeleteDatasetsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteDatasetsMenuSelected, true);
            app.DeleteDatasetsMenu.Text = 'Dataset(s)';
            app.DeleteTrialsMenu = uimenu(app.DeleteDataMenu);
            app.DeleteTrialsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteTrialsMenuSelected, true);
            app.DeleteTrialsMenu.Text = 'Trial(s)';
            app.DeleteCellsMenu = uimenu(app.DeleteDataMenu);
            app.DeleteCellsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCellsMenuSelected, true);
            app.DeleteCellsMenu.Text = 'Cell(s)';
            app.ExportDataMenu = uimenu(app.FileMenu);
            app.ExportDataMenu.Text = 'Export Data';
            app.ExportFullMenu = uimenu(app.ExportDataMenu);
            app.ExportFullMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportFullMenuSelected, true);
            app.ExportFullMenu.Text = 'Full';
            app.ExportMinimalMenu = uimenu(app.ExportDataMenu);
            app.ExportMinimalMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMinimalMenuSelected, true);
            app.ExportMinimalMenu.Text = 'Minimal';
            app.SettingsMenu = uimenu(app.mainWin);
            app.SettingsMenu.Text = 'Settings';
            app.SetDefaultsMenu = uimenu(app.SettingsMenu);
            app.SetDefaultsMenu.Text = 'Set Defaults';
            app.GUIMenu = uimenu(app.SetDefaultsMenu);
            app.GUIMenu.MenuSelectedFcn = createCallbackFcn(app, @GUIMenuSelected, true);
            app.GUIMenu.Text = 'GUI';
            app.GeneralPlottingMenu = uimenu(app.SetDefaultsMenu);
            app.GeneralPlottingMenu.MenuSelectedFcn = createCallbackFcn(app, @GeneralPlottingMenuSelected, true);
            app.GeneralPlottingMenu.Text = 'General Plotting';
            app.RateMapsMenu = uimenu(app.SetDefaultsMenu);
            app.RateMapsMenu.MenuSelectedFcn = createCallbackFcn(app, @RateMapsMenuSelected, true);
            app.RateMapsMenu.Text = 'Rate Maps';
            app.PauseUpdatesMenu = uimenu(app.SettingsMenu);
            app.PauseUpdatesMenu.Text = 'Pause Updates';
            app.FreezeMenu = uimenu(app.PauseUpdatesMenu);
            app.FreezeMenu.MenuSelectedFcn = createCallbackFcn(app, @FreezeMenuSelected, true);
            app.FreezeMenu.Text = 'Freeze';
            app.UnFreezeMenu = uimenu(app.PauseUpdatesMenu);
            app.UnFreezeMenu.MenuSelectedFcn = createCallbackFcn(app, @UnFreezeMenuSelected, true);
            app.UnFreezeMenu.Text = 'UnFreeze';
            app.FiguresMenu = uimenu(app.mainWin);
            app.FiguresMenu.Text = 'Figures';
            app.SaveasPDFMenu = uimenu(app.FiguresMenu);
            app.SaveasPDFMenu.Enable = 'off';
            app.SaveasPDFMenu.Text = 'Save as PDF';
            app.AllMenu = uimenu(app.SaveasPDFMenu);
            app.AllMenu.Text = 'All';
            app.GUIStateMenu = uimenu(app.SaveasPDFMenu);
            app.GUIStateMenu.Text = 'GUI State';
            app.CustomSelectionMenu = uimenu(app.SaveasPDFMenu);
            app.CustomSelectionMenu.Text = 'Custom Selection';
            app.NeuropixelsMenu = uimenu(app.FiguresMenu);
            app.NeuropixelsMenu.Text = 'Neuropixels';
            app.CellsMenu = uimenu(app.NeuropixelsMenu);
            app.CellsMenu.Text = 'Cells';
            app.plotWaveDistMenu = uimenu(app.CellsMenu);
            app.plotWaveDistMenu.MenuSelectedFcn = createCallbackFcn(app, @plotWaveDistMenuSelected, true);
            app.plotWaveDistMenu.Text = 'plot Wave Dist';
            app.DACQMenu = uimenu(app.FiguresMenu);
            app.DACQMenu.Enable = 'off';
            app.DACQMenu.Text = 'DACQ';
            app.FilterMenu = uimenu(app.mainWin);
            app.FilterMenu.Text = 'Filter';
            app.TrialsinDatasetListBoxLabel = uilabel(app.mainWin);
            app.TrialsinDatasetListBoxLabel.Text = {'Trials'; 'in'; 'Dataset'};
            app.TrialsinDatasetListBox = uilistbox(app.mainWin);
            app.TrialsinDatasetListBox.Items = {};
            app.TrialsinDatasetListBox.Multiselect = 'on';
            app.TrialsinDatasetListBox.ValueChangedFcn = createCallbackFcn(app, @TrialsinDatasetListBoxValueChanged, true);
            app.TrialsinDatasetListBox.Value = {};
            app.CellsinDatasetListBoxLabel = uilabel(app.mainWin);
            app.CellsinDatasetListBoxLabel.Text = {'Cells'; 'in'; 'Dataset'};
            app.CellsinDatasetListBox = uilistbox(app.mainWin);
            app.CellsinDatasetListBox.Items = {};
            app.CellsinDatasetListBox.Multiselect = 'on';
            app.CellsinDatasetListBox.ValueChangedFcn = createCallbackFcn(app, @CellsinDatasetListBoxValueChanged, true);
            app.CellsinDatasetListBox.Value = {};
            app.ExportDataButton = uibutton(app.mainWin, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFullMenuSelected, true);
            app.ExportDataButton.Text = 'Export Data';
            app.TabGroup = uitabgroup(app.mainWin);
            app.OverviewTab = uitab(app.TabGroup);
            app.OverviewTab.Title = 'Overview';
            app.wfs = uiaxes(app.OverviewTab);
            title(app.wfs, 'Waveform')
            xlabel(app.wfs, 'time (ms)')
            ylabel(app.wfs, '')
            app.wfs.XTick = [];
            app.wfs.YTick = [];
            app.ac_shortLag = uiaxes(app.OverviewTab);
            title(app.ac_shortLag, '')
            xlabel(app.ac_shortLag, 'time (ms)')
            ylabel(app.ac_shortLag, '')
            app.ac_shortLag.XLim = [-0.02 0.02];
            app.ac_shortLag.XTick = [];
            app.ac_shortLag.XTickLabel = '';
            app.ac_shortLag.YTick = [];
            app.spikepos = uiaxes(app.OverviewTab);
            title(app.spikepos, 'Spike Pos')
            xlabel(app.spikepos, '')
            ylabel(app.spikepos, '')
            app.spikepos.XTick = [];
            app.spikepos.YTick = [];
            app.rmap = uiaxes(app.OverviewTab);
            title(app.rmap, 'Rate Map')
            xlabel(app.rmap, '')
            ylabel(app.rmap, '')
            app.rmap.XTick = [];
            app.rmap.YTick = [];
            app.varmap = uiaxes(app.OverviewTab);
            title(app.varmap, 'Var. Data Plot')
            xlabel(app.varmap, '')
            ylabel(app.varmap, '')
            app.varmap.XTick = [];
            app.varmap.YTick = [];
            app.dmap = uiaxes(app.OverviewTab);
            title(app.dmap, 'Dir Map')
            xlabel(app.dmap, '')
            ylabel(app.dmap, '')
            app.dmap.XTick = [];
            app.dmap.YTick = [];
            app.nSpikesTxt = uieditfield(app.OverviewTab, 'text');
            app.nSpikesTxt.Editable = 'off';
            app.nSpikesTxt.Value = 'nSpikes:';
            app.nSpikes = uieditfield(app.OverviewTab, 'numeric');
            app.nSpikes.Editable = 'off';
            app.amplitudeTxt = uieditfield(app.OverviewTab, 'text');
            app.amplitudeTxt.Editable = 'off';
            app.amplitudeTxt.Value = 'amplitude:';
            app.isodTxt = uieditfield(app.OverviewTab, 'text');
            app.isodTxt.Editable = 'off';
            app.isodTxt.Value = 'iso D:';
            app.lratioTxt = uieditfield(app.OverviewTab, 'text');
            app.lratioTxt.Editable = 'off';
            app.lratioTxt.Value = 'L-ratio:';
            app.amplitude = uieditfield(app.OverviewTab, 'numeric');
            app.amplitude.Editable = 'off';
            app.lratio = uieditfield(app.OverviewTab, 'numeric');
            app.lratio.Editable = 'off';
            app.isod = uieditfield(app.OverviewTab, 'numeric');
            app.isod.Editable = 'off';
            app.MakeRMapsButton = uibutton(app.OverviewTab, 'push');
            app.MakeRMapsButton.ButtonPushedFcn = createCallbackFcn(app, @MakeRMapsButtonPushed, true);
            app.MakeRMapsButton.Text = 'Make Maps';
            app.varmapButton = uibutton(app.OverviewTab, 'push');
            app.varmapButton.ButtonPushedFcn = createCallbackFcn(app, @varmapButtonPushed, true);
            app.varmapButton.Text = 'Make Plots';
            app.LoadWaveForms = uibutton(app.OverviewTab, 'push');
            app.LoadWaveForms.ButtonPushedFcn = createCallbackFcn(app, @LoadWaveFormsPushed, true);
            app.LoadWaveForms.Enable = 'off';
            app.LoadWaveForms.Visible = 'off';
            app.LoadWaveForms.Text = {'Load '; 'Waveforms'};
            app.MakeDMapsButton = uibutton(app.OverviewTab, 'push');
            app.MakeDMapsButton.ButtonPushedFcn = createCallbackFcn(app, @MakeRMapsButtonPushed, true);
            app.MakeDMapsButton.Text = 'Make Maps';
            app.ac_longLag = uiaxes(app.OverviewTab);
            title(app.ac_longLag, '')
            xlabel(app.ac_longLag, 'time (ms)')
            ylabel(app.ac_longLag, '')
            app.ac_longLag.XLim = [-0.02 0.02];
            app.ac_longLag.XTick = [];
            app.ac_longLag.XTickLabel = '';
            app.ac_longLag.YTick = [];
            app.PanelAllTrials = uipanel(app.OverviewTab);
            app.SelectPlotstyleLabel = uilabel(app.OverviewTab);
            app.SelectPlotstyleLabel.Text = 'Select Plotstyle';
            app.varMapList = uilistbox(app.OverviewTab);
            app.varMapList.Items = {'Spatial AC', 'Speed Mod.'};
            app.varMapList.ValueChangedFcn = createCallbackFcn(app, @varMapListValueChanged, true);
            app.varMapList.Value = 'Spatial AC';
            app.cluLabelTxt = uieditfield(app.OverviewTab, 'text');
            app.cluLabelTxt.Editable = 'off';
            app.cluLabelTxt.Value = 'clust. label: ';
            app.cluLabel = uieditfield(app.OverviewTab, 'text');
            app.cluLabel.Value = 'n/a';
            app.CompareCellsTab = uitab(app.TabGroup);
            app.CompareCellsTab.Title = 'Compare Cells';
            app.dummy = uiaxes(app.CompareCellsTab);
            title(app.dummy, '')
            xlabel(app.dummy, '')
            ylabel(app.dummy, '')
            app.dummy.XLim = [-0.02 0.02];
            app.dummy.XTick = [];
            app.dummy.XTickLabel = '';
            app.dummy.YTick = [];
            app.dummy_2 = uiaxes(app.CompareCellsTab);
            title(app.dummy_2, '')
            xlabel(app.dummy_2, '')
            ylabel(app.dummy_2, '')
            app.dummy_2.XLim = [-0.02 0.02];
            app.dummy_2.XTick = [];
            app.dummy_2.XTickLabel = '';
            app.dummy_2.YTick = [];
            app.dummy_3 = uiaxes(app.CompareCellsTab);
            title(app.dummy_3, '')
            xlabel(app.dummy_3, '')
            ylabel(app.dummy_3, '')
            app.dummy_3.XLim = [-0.02 0.02];
            app.dummy_3.XTick = [];
            app.dummy_3.XTickLabel = '';
            app.dummy_3.YTick = [];
            app.dummy_4 = uiaxes(app.CompareCellsTab);
            title(app.dummy_4, '')
            xlabel(app.dummy_4, '')
            ylabel(app.dummy_4, '')
            app.dummy_4.XLim = [-0.02 0.02];
            app.dummy_4.XTick = [];
            app.dummy_4.XTickLabel = '';
            app.dummy_4.YTick = [];
            app.dummy_5 = uiaxes(app.CompareCellsTab);
            title(app.dummy_5, '')
            xlabel(app.dummy_5, '')
            ylabel(app.dummy_5, '')
            app.dummy_5.XLim = [-0.02 0.02];
            app.dummy_5.XTick = [];
            app.dummy_5.XTickLabel = '';
            app.dummy_5.YTick = [];
            app.dummy_6 = uiaxes(app.CompareCellsTab);
            title(app.dummy_6, '')
            xlabel(app.dummy_6, '')
            ylabel(app.dummy_6, '')
            app.dummy_6.XLim = [-0.02 0.02];
            app.dummy_6.XTick = [];
            app.dummy_6.XTickLabel = '';
            app.dummy_6.YTick = [];
            app.dummy_7 = uiaxes(app.CompareCellsTab);
            title(app.dummy_7, '')
            xlabel(app.dummy_7, '')
            ylabel(app.dummy_7, '')
            app.dummy_7.XLim = [-0.02 0.02];
            app.dummy_7.XTick = [];
            app.dummy_7.XTickLabel = '';
            app.dummy_7.YTick = [];
            app.dummy_8 = uiaxes(app.CompareCellsTab);
            title(app.dummy_8, '')
            xlabel(app.dummy_8, '')
            ylabel(app.dummy_8, '')
            app.dummy_8.XLim = [-0.02 0.02];
            app.dummy_8.XTick = [];
            app.dummy_8.XTickLabel = '';
            app.dummy_8.YTick = [];
            app.EditField_A1 = uieditfield(app.CompareCellsTab, 'text');
            app.EditField_A1.Editable = 'off';
            app.EditField_B1 = uieditfield(app.CompareCellsTab, 'text');
            app.EditField_B1.Editable = 'off';
            app.CC_selCellsB = uilistbox(app.CompareCellsTab);
            app.CC_selCellsB.Items = {};
            app.CC_selCellsB.ValueChangedFcn = createCallbackFcn(app, @CC_selCellsBValueChanged, true);
            app.CC_selCellsB.Value = {};
            app.CC_selCellsA = uilistbox(app.CompareCellsTab);
            app.CC_selCellsA.Items = {};
            app.CC_selCellsA.ValueChangedFcn = createCallbackFcn(app, @CC_selCellsAValueChanged, true);
            app.CC_selCellsA.Value = {};
            app.CompareDropDownLabel = uilabel(app.CompareCellsTab);
            app.CompareDropDownLabel.Text = 'Compare?';
            app.CompareDropDown = uidropdown(app.CompareCellsTab);
            app.CompareDropDown.Items = {};
            app.CompareDropDown.Value = {};
            app.LineariseMeTab = uitab(app.TabGroup);
            app.LineariseMeTab.Title = 'Linearise Me';
            app.PlotSelectionButton = uibutton(app.mainWin, 'push');
            app.PlotSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSelectionButtonPushed, true);
            app.PlotSelectionButton.Text = 'Plot Selection';
            app.LoadDataButton = uibutton(app.mainWin, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.DatasetLabel = uilabel(app.mainWin);
            app.DatasetLabel.Text = 'Dataset';
            app.DatasetEditField = uieditfield(app.mainWin, 'text');
            app.DatasetEditField.ValueChangedFcn = createCallbackFcn(app, @DatasetEditFieldValueChanged2, true);
            app.TrialEditFieldLabel = uilabel(app.mainWin);
            app.TrialEditFieldLabel.Text = 'Trial';
            app.TrialEditField = uieditfield(app.mainWin, 'text');
            app.TrialEditField.Editable = 'off';
            app.CellEditFieldLabel = uilabel(app.mainWin);
            app.CellEditFieldLabel.Text = 'Cell #';
            app.CellEditField = uieditfield(app.mainWin, 'text');
            app.CellEditField.Editable = 'off';
            app.SelectDatasetsListBoxLabel = uilabel(app.mainWin);
            app.SelectDatasetsListBoxLabel.Text = {'Select'; 'Datasets'};
            app.SelectDatasetsListBox = uilistbox(app.mainWin);
            app.SelectDatasetsListBox.Items = {};
            app.SelectDatasetsListBox.Multiselect = 'on';
            app.SelectDatasetsListBox.ValueChangedFcn = createCallbackFcn(app, @SelectDatasetsListBoxValueChanged, true);
            app.SelectDatasetsListBox.Value = {};
            app.SelectAllCells = uibutton(app.mainWin, 'push');
            app.SelectAllCells.ButtonPushedFcn = createCallbackFcn(app, @SelectAllCellsPushed, true);
            app.SelectAllCells.Text = {'Select'; 'All'};
            app.SelectAllTrials = uibutton(app.mainWin, 'push');
            app.SelectAllTrials.ButtonPushedFcn = createCallbackFcn(app, @SelectAllTrialsPushed, true);
            app.SelectAllTrials.Text = {'Select'; 'All'};
            app.ExportFiguresButton = uibutton(app.mainWin, 'push');
            app.ExportFiguresButton.Enable = 'off';
            app.ExportFiguresButton.Text = 'Export Figures';
            app.Button_2 = uibutton(app.mainWin, 'push');
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.mainWin, 'push');
            app.Button_3.Enable = 'off';
            app.Button_3.Text = '';
            app.SelectAllDatasets = uibutton(app.mainWin, 'push');
            app.SelectAllDatasets.ButtonPushedFcn = createCallbackFcn(app, @SelectAllDatasetsButtonPushed, true);
            app.SelectAllDatasets.Text = {'Select'; 'All'};
            % Show the figure after all components are created
            app.mainWin.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainWin)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainWin)
        end
    end
end